classdef BER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SimulateButton         matlab.ui.control.Button
        SNRdBSlider            matlab.ui.control.Slider
        SNRdBSliderLabel       matlab.ui.control.Label
        ChooseMEditField       matlab.ui.control.NumericEditField
        ChooseMEditFieldLabel  matlab.ui.control.Label
        CommunicationLinkSimulationLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            N=10^5; % No. of bits to be transmitted
            M=app.ChooseMEditField.Value; % M = order of PSK
            d=round(rand(1,10^5));  % input data
            y=pskmod(d,M);  
            x=app.SNRdBSlider.Value; %Starting value of SNR in dB
            snr_db=x:1:10;
            ber=[];         % stores simulated BER values
            tber=[];        % stores theoretical BER values
            db=[];
            for i=1:length(snr_db)
                snr(i)=power(10, snr_db(i)/10);
                s=awgn(y,snr_db(i),'measured');
                output=pskdemod(s,M);   %demodulation of noisy signal
                [number(1),ber(i)]=biterr(d,output);
                if M==2
                    tber(i)=0.5*erfc(sqrt(snr(i)));
                else
                    tber(i)=erfc(sqrt(snr(i)*sin(pi/M)));
                end
            end
            semilogy(app.UIAxes,snr_db,ber,'mo-',snr_db,tber,'bs-');
            grid on;
            axis('tight');
            legend("Simulated","Theoretical");
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Performance Evaluation of M-ary PSK Modulation')
            xlabel(app.UIAxes, 'SNR (dB)')
            ylabel(app.UIAxes, 'Bit Error Rate')
            zlabel(app.UIAxes, 'Z')
            app.CommunicationLinkSimulationLabel = uilabel(app.UIFigure);
            app.CommunicationLinkSimulationLabel.Text = 'Communication Link Simulation';
            app.ChooseMEditFieldLabel = uilabel(app.UIFigure);
            app.ChooseMEditFieldLabel.Text = 'Choose M';
            app.ChooseMEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNRdBSliderLabel = uilabel(app.UIFigure);
            app.SNRdBSliderLabel.Text = 'SNR (dB)';
            app.SNRdBSlider = uislider(app.UIFigure);
            app.SNRdBSlider.Limits = [-10 10];
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end