classdef constellation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SNREditField               matlab.ui.control.NumericEditField
        SNREditFieldLabel          matlab.ui.control.Label
        MEditField                 matlab.ui.control.NumericEditField
        MEditFieldLabel            matlab.ui.control.Label
        NosymbolsEditField         matlab.ui.control.NumericEditField
        NosymbolsEditFieldLabel    matlab.ui.control.Label
        CONSTELLATIONDIAGRAMLabel  matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        snr % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)
            snr=app.snr

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

    SNR_dB = app.SNREditField.Value;  % Retrieve SNR value from input field
    noise_variance = 10^(-SNR_dB / 10);  % Calculate noise variance based on SNR
    
    if (app.DropDown.Value == "BPSK")
        % BPSK signal generation
        limit= app.NosymbolsEditField.Value;
        data = randi([0, 1], 1, limit);  % Generate random data bits
        bpsk_symbols = 2 * data - 1;  % Map 0 to -1, 1 to +1
        
        % Add noise to BPSK symbols
        noisy_bpsk_symbols = bpsk_symbols + sqrt(noise_variance) * (randn(1, length(bpsk_symbols)) + 1j * randn(1, length(bpsk_symbols)));
        
        % Plot constellation
        plot(app.UIAxes, real(noisy_bpsk_symbols), imag(noisy_bpsk_symbols), 'o');
        title(app.UIAxes, 'BPSK Constellation with Noise');
        xlabel(app.UIAxes, 'Real');
        ylabel(app.UIAxes, 'Imaginary');

        %------------------------------------------------------------------------------------------------------

        data_NZR=2*data-1; % Data Represented at NZR form for QPSK modulation

            br=10.^6; %Let us transmission bit rate  1000000
            f=br; % minimum carrier frequency
            T=1/br; % bit duration
            t=T/99:T/99:T; % Time vector for one bit information
            y=[];
            y_in=[];
            d=zeros(1,length(data)/2);
            for i=1:length(data)
            
                if (data(i)==0)
                   d(i)=exp(j*0*pi/4);%45 degrees
                   %y(i)=cos(2*pi*f*t);
                   y2=cos(2*pi*f*t) ;% Quadrature component
                   y_in=[y_in y2]; % inphase signal vector
                end
                if(data(i)==1)
                    d(i)=exp(j*4*pi/4);
                    %y(i)=cos(2*pi*f*t+pi);
                    y2=cos(2*pi*f*t+ pi) ;% Quadrature component
                    y_in=[y_in y2]; % inphase signal vector
                end
            
            end
            for i=1:length(d)
                d(i)=d(i)+rand(1)/10;
            end
            Tx_sig=y; % transmitting signal after modulation
            qpsk=d;
            tt=T/99:T/99:(T*length(data));

            plot(app.UIAxes2,tt,y_in,'linewidth',3), grid on;
            title(app.UIAxes2,' wave form for BPSK ');
            xlabel(app.UIAxes,'time(sec)');
            ylabel(app.UIAxes,' amplitude volt');
            xlim(app.UIAxes2, [0, T * 10]); % Set x-axis to show only the first 10 bits
            ylim(app.UIAxes2, [-1.5, 1.5]); % Set y-axis to keep amplitude range visible
            %---------------------------------------------------------------------------------------------------------------
                    
    elseif (app.DropDown.Value == "QPSK")
        % QPSK signal generation
        data = randi([0, 1], 1, 200);  % Generate random data bits
        data_reshaped = reshape(data, 2, []).';  % Group bits into pairs
        qpsk_symbols = (2 * data_reshaped(:, 1) - 1) + 1j * (2 * data_reshaped(:, 2) - 1);  % Map bits to QPSK symbols
        
        % Add noise to QPSK symbols
        noisy_qpsk_symbols = qpsk_symbols + sqrt(noise_variance) * (randn(size(qpsk_symbols)) + 1j * randn(size(qpsk_symbols)));
        
        % Plot constellation
        plot(app.UIAxes, real(noisy_qpsk_symbols), imag(noisy_qpsk_symbols), 'o');
        title(app.UIAxes, 'QPSK Constellation with Noise');
        xlabel(app.UIAxes, 'Real');
        ylabel(app.UIAxes, 'Imaginary');
        %------------------------------------------------------------------------------------------------------------------

        data_NZR=2*data-1; % Data Represented at NZR form for QPSK modulation
        s_p_data=reshape(data_NZR,2,length(data)/2);  % S/P convertion of data
        br=10.^6; %Let us transmission bit rate  1000000
        f=br; % minimum carrier frequency
        T=1/br; % bit duration
        t=T/99:T/99:T; % Time vector for one bit information
        y=[];
        y_in=[];
        y_qd=[];
        d=zeros(1,length(data)/2);
        for i=1:length(data)/2
            p=data(2*i);
            imp=data(2*i - 1);
            y1=s_p_data(1,i)*cos(2*pi*f*t); % inphase component
            y2=s_p_data(2,i)*sin(2*pi*f*t) ;% Quadrature component
            y_in=[y_in y1]; % inphase signal vector
            y_qd=[y_qd y2]; %quadrature signal vector
            y=[y y1+y2]; % modulated signal vector
            if (imp == 0) && (p == 0)
               d(i)=exp(j*pi/4);%45 degrees
            end
            if (imp == 1)&&(p == 0)
                d(i)=exp(j*3*pi/4);%135 degrees
            end
            if (imp == 1)&&(p == 1)
                d(i)=exp(j*5*pi/4);%225 degrees
            end
            if (imp == 0)&&(p == 1)
                d(i)=exp(j*7*pi/4);%315 degrees
            end
        end
        for i=1:length(d)
            d(i)=d(i)+rand(1)/10;
        end
        Tx_sig=y; % transmitting signal after modulation
        qpsk=d;
        tt=T/99:T/99:(T*length(data))/2;
        plot(app.UIAxes2,tt,Tx_sig,'r','linewidth',3), grid on;
        title(app.UIAxes2,'QPSK modulated signal (sum of inphase and Quadrature phase signal)');
        xlabel(app.UIAxes2,'time(sec)');
        ylabel(app.UIAxes2,' amplitude(volt0');
        xlim(app.UIAxes2, [0, T * 10]); % Set x-axis to show only the first 10 bits
        ylim(app.UIAxes2, [-1.5, 1.5]); % Set y-axis to keep amplitude range visible
%------------------------------------------------------------------------------------------------------------
    elseif (app.DropDown.Value == "QAM")
        % 16-QAM signal generation
        data = randi([0, 1], 1, 400);  % Generate random data bits
        data_reshaped = reshape(data, 4, []).';  % Group bits into groups of 4
        bit2symbol = [
            -3+3j, -3+1j, -3-3j, -3-1j;
            -1+3j, -1+1j, -1-3j, -1-1j;
             1+3j,  1+1j,  1-3j,  1-1j;
             3+3j,  3+1j,  3-3j,  3-1j
        ];
        
        % Map bits to symbols
        qam_symbols = zeros(1, size(data_reshaped, 1));
        for i = 1:size(data_reshaped, 1)
            row = bi2de(data_reshaped(i, 1:2), 'left-msb') + 1;
            col = bi2de(data_reshaped(i, 3:4), 'left-msb') + 1;
            qam_symbols(i) = bit2symbol(row, col);
        end
        
        % Add noise to 16-QAM symbols
        noisy_qam_symbols = qam_symbols + sqrt(noise_variance) * (randn(size(qam_symbols)) + 1j * randn(size(qam_symbols)));
        
        % Plot constellation
        plot(app.UIAxes, real(noisy_qam_symbols), imag(noisy_qam_symbols), 'o');
        title(app.UIAxes, '16-QAM Constellation with Noise');
        xlabel(app.UIAxes, 'Real');
        ylabel(app.UIAxes, 'Imaginary');
        %----------------------------------------------------------------------------------------------------------------
                % Generate time vector
        br = 10^6; % Transmission bit rate 1 Mbps
        f = br; % Minimum carrier frequency
        T = 1/br; % Bit duration
        t = T/99:T/99:T; % Time vector for one bit information
        y_in = [];
        
        % QAM signal generation
        for i = 1:length(qam_symbols)
            y2 = real(qam_symbols(i)) * cos(2*pi*f*t) - imag(qam_symbols(i)) * sin(2*pi*f*t);
            y_in = [y_in y2]; % Append to signal
        end
        
        tt = T/99:T/99:(T*length(qam_symbols)); % Time vector for the entire signal
        
        subplot(2,1,2);
        plot(app.UIAxes2,tt, y_in, 'linewidth', 3), grid on;
        title(app.UIAxes2,'Waveform for 16-QAM Signal');
        xlabel(app.UIAxes2,'Time (sec)');
        ylabel(app.UIAxes2,'Amplitude (V)');
        xlim(app.UIAxes2, [0, T * 10]); % Set x-axis to show only the first 10 bits
        ylim(app.UIAxes2, [-1.5, 1.5]); % Set y-axis to keep amplitude range visible
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'BPSK', 'QAM', 'QPSK'};
            app.DropDown.Value = 'BPSK';
            app.CONSTELLATIONDIAGRAMLabel = uilabel(app.UIFigure);
            app.CONSTELLATIONDIAGRAMLabel.Text = 'CONSTELLATION DIAGRAM';
            app.NosymbolsEditFieldLabel = uilabel(app.UIFigure);
            app.NosymbolsEditFieldLabel.Text = 'No.symbols';
            app.NosymbolsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NosymbolsEditField.Placeholder = '0';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditField.Placeholder = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = constellation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end